classdef t2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EsteganografiaTrabalho2UIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InserirTab                      matlab.ui.container.Tab
        imagemOriginal                  matlab.ui.control.UIAxes
        imagemEsteganografia            matlab.ui.control.UIAxes
        CarregarImagemPanel             matlab.ui.container.Panel
        EncontrarButton                 matlab.ui.control.Button
        SelecioneoarquivodaimagemLabel  matlab.ui.control.Label
        InserirTextoPanel               matlab.ui.container.Panel
        TextoinseridoEditFieldLabel     matlab.ui.control.Label
        TextoinseridoEditField          matlab.ui.control.EditField
        InserirButton                   matlab.ui.control.Button
        ChaveEditField_2Label           matlab.ui.control.Label
        ChaveInserir                    matlab.ui.control.EditField
        ObterTextoPanel                 matlab.ui.container.Panel
        ObterButton                     matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        MensagemLabel                   matlab.ui.control.Label
        SalvarImagemPanel               matlab.ui.container.Panel
        SalvarButton                    matlab.ui.control.Button
        ExtrairTab                      matlab.ui.container.Tab
        ObterTextoPanel_2               matlab.ui.container.Panel
        ObterExtrair                    matlab.ui.control.Button
        MensagemExtrairLabel            matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        CarregarImagemPanel_2           matlab.ui.container.Panel
        EncontrarExtrair                matlab.ui.control.Button
        SelecioneoarquivodaimagemLabel_2  matlab.ui.control.Label
        imagemExtrair                   matlab.ui.control.UIAxes
        DecifrarTab                     matlab.ui.container.Tab
        MensagemOriginalTextAreaLabel   matlab.ui.control.Label
        MensagemOriginalTextArea        matlab.ui.control.TextArea
        DecifrarButton                  matlab.ui.control.Button
        MensagemCifradaEditFieldLabel   matlab.ui.control.Label
        MensagemCifradaEditField        matlab.ui.control.EditField
        ChaveEditField_2Label_2         matlab.ui.control.Label
        ChaveDecifrar                   matlab.ui.control.EditField
    end


    properties (Access = private)
        imagemCarregada
        imagemProcessada
        text
        len
        lenRecebida
        numeroImagem
    end

    methods (Access = private)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.imagemOriginal, 'XTick', []);
            set(app.imagemOriginal, 'YTick', []);
            set(app.imagemEsteganografia, 'XTick', []);
            set(app.imagemEsteganografia, 'YTick', []);
            set(app.imagemExtrair, 'XTick', []);
            set(app.imagemExtrair, 'YTick', []);
            app.numeroImagem = 0;
        end

        % Button pushed function: EncontrarButton
        function EncontrarButtonPushed(app, event)
            [filename,user_canceled] = imgetfile();
            figure(app.EsteganografiaTrabalho2UIFigure);
            if user_canceled == false
                app.imagemOriginal.Color = 'white';
                app.imagemCarregada = imread(filename);
                app.imagemProcessada = app.imagemCarregada;
                imshow(app.imagemCarregada, 'Parent', app.imagemOriginal);  
                cla(app.imagemEsteganografia);
            end   
        end

        % Button pushed function: InserirButton
        function InserirButtonPushed(app, event)
            %Obtendo tamanho da imagem
            [row,column]=size(app.imagemCarregada);
            %Obtendo texto a ser inserido na imagem
            app.text = Cipher(app.ChaveInserir.Value, app.TextoinseridoEditField.Value);
            %Calculando o número de bits na mensagem
            app.len = strlength(app.text)*8;
            %Vetor ascii possui o valor na tabela ascii de cada caractere
            ascii = uint8(app.text);
            %Vetor binary_separate possui o valor de cada caractere em decimal
            binary_separate=dec2bin(ascii,8);
            %Vetor binary_all será composto pela sequência de bits da mensagem
            binary_all='';
            for i=1:strlength(app.text)
                binary_all=[binary_all,binary_separate(i,:)];
            end
            
            count=1;

            for i=1:row
                for j=1:column
                   
                    %Para cada caractere
                    if count<=app.len
                        %Pegando o bit menos significante de cada pixel
                        LSB=mod(app.imagemCarregada(i,j),2);
            
                        %Convertendo o bit da mensagem para forma numérica
                        a=str2double(binary_all(count));

                        %XOR entre o bit menos significante e o bit da mensagem
                        temp=double(xor(LSB,a));
            
                        %Trocando o bit da imagem
                        app.imagemProcessada(i,j)=app.imagemCarregada(i,j)+temp;
            
                        count=count+1;
                    end
                end
            end
            imshow(app.imagemProcessada, 'Parent', app.imagemEsteganografia);  
        end

        % Button pushed function: ObterButton
        function ObterButtonPushed(app, event)
            [row,column]=size(app.imagemCarregada);
            count=1;
            message_in_bits='';
            for i=1:row
                for j=1:column
                    %Para todos os caracteres
                        
                        %Obtendo o bit menos significativo
                        LSB=mod(app.imagemProcessada(i,j),2);
                        
                        %Concatenando a mensagem em bits
                        message_in_bits=[message_in_bits,num2str(LSB)];
                        
                        count=count+1;
                end
            end
            
            %Convertendo a mensagem em bits para a mensagem original
            i=1;
            original_message='';
            while i<=row*column
                %Pega 8 bits por vez
                %Converte para decimal
                %Transforma em caractere pela ascii
                %Concatena para formar a mensagem
                original_message=[original_message,char(bin2dec(message_in_bits(1,i:i+7)))];
                i=i+8;
            end
            app.TextArea.Value = original_message;
        end

        % Button pushed function: SalvarButton
        function SalvarButtonPushed(app, event)
            a = num2str(app.numeroImagem);
            string = "imagemEsteganografia" + a + ".png";
            imwrite(app.imagemProcessada, convertStringsToChars(string));
            app.numeroImagem = app.numeroImagem + 1;
        end

        % Button pushed function: EncontrarExtrair
        function EncontrarExtrairButtonPushed(app, event)
            [filename,user_canceled] = imgetfile();
            figure(app.EsteganografiaTrabalho2UIFigure);
            if user_canceled == false
                app.imagemOriginal.Color = 'white';
                app.imagemCarregada = imread(filename);
                app.imagemProcessada = app.imagemCarregada;
                imshow(app.imagemCarregada, 'Parent', app.imagemExtrair);  
            end   
        end

        % Button pushed function: ObterExtrair
        function ObterExtrairButtonPushed(app, event)
            [row,column]=size(app.imagemCarregada);
            L=256;
            count=1;
            message_in_bits='';
            for i=1:row
                for j=1:column
                    %Para todos os caracteres
                        
                        %Obtendo o bit menos significativo
                        LSB=mod(app.imagemProcessada(i,j),2);
                        
                        %Concatenando a mensagem em bits
                        message_in_bits=[message_in_bits,num2str(LSB)];
                        
                        count=count+1;
                end
            end
            
            %Convertendo a mensagem em bits para a mensagem original
            i=1;
            original_message='';
            while i<=row*column
                %Pega 8 bits por vez
                %Converte para decimal
                %Transforma em caractere pela ascii
                %Concatena para formar a mensagem
                original_message=[original_message,char(bin2dec(message_in_bits(1,i:i+7)))];
                i=i+8;
            end
            app.TextArea_2.Value = original_message;
        end

        % Button pushed function: DecifrarButton
        function DecifrarButtonPushed(app, event)
            string = app.MensagemCifradaEditField.Value(1:32);
            app.MensagemOriginalTextArea.Value = InvCipher(app.ChaveDecifrar.Value, string);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EsteganografiaTrabalho2UIFigure = uifigure;
            app.EsteganografiaTrabalho2UIFigure.Name = 'Esteganografia - Trabalho 2';
            app.TabGroup = uitabgroup(app.EsteganografiaTrabalho2UIFigure);
            app.InserirTab = uitab(app.TabGroup);
            app.InserirTab.Title = 'Inserir';
            app.imagemOriginal = uiaxes(app.InserirTab);
            title(app.imagemOriginal, 'Original')
            app.imagemEsteganografia = uiaxes(app.InserirTab);
            title(app.imagemEsteganografia, 'Pós Esteganografia')
            app.CarregarImagemPanel = uipanel(app.InserirTab);
            app.CarregarImagemPanel.Title = 'Carregar Imagem';
            app.EncontrarButton = uibutton(app.CarregarImagemPanel, 'push');
            app.EncontrarButton.ButtonPushedFcn = createCallbackFcn(app, @EncontrarButtonPushed, true);
            app.EncontrarButton.Text = 'Encontrar';
            app.SelecioneoarquivodaimagemLabel = uilabel(app.CarregarImagemPanel);
            app.SelecioneoarquivodaimagemLabel.Text = 'Selecione o arquivo da imagem';
            app.InserirTextoPanel = uipanel(app.InserirTab);
            app.InserirTextoPanel.Title = 'Inserir Texto';
            app.TextoinseridoEditFieldLabel = uilabel(app.InserirTextoPanel);
            app.TextoinseridoEditFieldLabel.Text = 'Texto inserido';
            app.TextoinseridoEditField = uieditfield(app.InserirTextoPanel, 'text');
            app.InserirButton = uibutton(app.InserirTextoPanel, 'push');
            app.InserirButton.ButtonPushedFcn = createCallbackFcn(app, @InserirButtonPushed, true);
            app.InserirButton.Text = 'Inserir';
            app.ChaveEditField_2Label = uilabel(app.InserirTextoPanel);
            app.ChaveEditField_2Label.Text = 'Chave';
            app.ChaveInserir = uieditfield(app.InserirTextoPanel, 'text');
            app.ObterTextoPanel = uipanel(app.InserirTab);
            app.ObterTextoPanel.Title = 'Obter Texto';
            app.ObterButton = uibutton(app.ObterTextoPanel, 'push');
            app.ObterButton.ButtonPushedFcn = createCallbackFcn(app, @ObterButtonPushed, true);
            app.ObterButton.Text = 'Obter';
            app.TextArea = uitextarea(app.ObterTextoPanel);
            app.MensagemLabel = uilabel(app.ObterTextoPanel);
            app.MensagemLabel.Text = 'Mensagem:';
            app.SalvarImagemPanel = uipanel(app.InserirTab);
            app.SalvarImagemPanel.Title = 'Salvar Imagem';
            app.SalvarButton = uibutton(app.SalvarImagemPanel, 'push');
            app.SalvarButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarButtonPushed, true);
            app.SalvarButton.Text = 'Salvar';
            app.ExtrairTab = uitab(app.TabGroup);
            app.ExtrairTab.Title = 'Extrair';
            app.ObterTextoPanel_2 = uipanel(app.ExtrairTab);
            app.ObterTextoPanel_2.Title = 'Obter Texto';
            app.ObterExtrair = uibutton(app.ObterTextoPanel_2, 'push');
            app.ObterExtrair.ButtonPushedFcn = createCallbackFcn(app, @ObterExtrairButtonPushed, true);
            app.ObterExtrair.Text = 'Obter';
            app.MensagemExtrairLabel = uilabel(app.ObterTextoPanel_2);
            app.MensagemExtrairLabel.Text = 'Mensagem:';
            app.TextArea_2 = uitextarea(app.ObterTextoPanel_2);
            app.CarregarImagemPanel_2 = uipanel(app.ExtrairTab);
            app.CarregarImagemPanel_2.Title = 'Carregar Imagem';
            app.EncontrarExtrair = uibutton(app.CarregarImagemPanel_2, 'push');
            app.EncontrarExtrair.ButtonPushedFcn = createCallbackFcn(app, @EncontrarExtrairButtonPushed, true);
            app.EncontrarExtrair.Text = 'Encontrar';
            app.SelecioneoarquivodaimagemLabel_2 = uilabel(app.CarregarImagemPanel_2);
            app.SelecioneoarquivodaimagemLabel_2.Text = 'Selecione o arquivo da imagem';
            app.imagemExtrair = uiaxes(app.ExtrairTab);
            title(app.imagemExtrair, 'Original')
            app.DecifrarTab = uitab(app.TabGroup);
            app.DecifrarTab.Title = 'Decifrar';
            app.MensagemOriginalTextAreaLabel = uilabel(app.DecifrarTab);
            app.MensagemOriginalTextAreaLabel.Text = 'Mensagem Original';
            app.MensagemOriginalTextArea = uitextarea(app.DecifrarTab);
            app.MensagemOriginalTextArea.Editable = 'off';
            app.DecifrarButton = uibutton(app.DecifrarTab, 'push');
            app.DecifrarButton.ButtonPushedFcn = createCallbackFcn(app, @DecifrarButtonPushed, true);
            app.DecifrarButton.Text = 'Decifrar';
            app.MensagemCifradaEditFieldLabel = uilabel(app.DecifrarTab);
            app.MensagemCifradaEditFieldLabel.Text = 'Mensagem Cifrada';
            app.MensagemCifradaEditField = uieditfield(app.DecifrarTab, 'text');
            app.ChaveEditField_2Label_2 = uilabel(app.DecifrarTab);
            app.ChaveEditField_2Label_2.Text = 'Chave';
            app.ChaveDecifrar = uieditfield(app.DecifrarTab, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = t2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EsteganografiaTrabalho2UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EsteganografiaTrabalho2UIFigure)
        end
    end
end